classdef FINALCompass_last < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Image                         matlab.ui.control.Image
        ClearAccelerationDataButton   matlab.ui.control.Button
        ClearCompassDataButton        matlab.ui.control.Button
        LoadDataButton                matlab.ui.control.Button
        ClearMagneticFieldDataButton  matlab.ui.control.Button
        SensorGroupOneCompassLabel    matlab.ui.control.Label
        UIAccelerationTable           matlab.ui.control.Table
        CreateAccelerationCSVButton   matlab.ui.control.Button
        DisplayStdEditField           matlab.ui.control.NumericEditField
        UICompassTable                matlab.ui.control.Table
        DisplayMeanXYButton           matlab.ui.control.Button
        EditField                     matlab.ui.control.NumericEditField
        DisplayStdXYButton            matlab.ui.control.Button
        CreateCompassCSVButton        matlab.ui.control.Button
        ReadThingSpeakButton          matlab.ui.control.Button
        UploadtoThingSpeakButton      matlab.ui.control.Button
        UIAccelerationGraph           matlab.ui.control.UIAxes
        UICompassGraph                matlab.ui.control.UIAxes
        UIMagneticFieldGraph          matlab.ui.control.UIAxes
    end

 
  % Group Members: Jasleen Sohal, Isha Date, Mark Hagiwara, and Abraham Hadaf
    
    properties (Access = public)
     c1 % Description
        readAPIKey= 'RTI3U98DFO7PGA62' % Description
    end
    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
           
            
         [filename,filepath] = uigetfile('*.mat','Select a File to Open');
          if filename == 0
              return;
          end
          fullname = [filepath, filename];
          data = load(fullname);
           
          if isfield(data, 'Acceleration')
              app.UIAccelerationTable.Data = timetable2table(data.Acceleration);
              accelerationX = table2array(data.Acceleration(:,1));
              accelerationY = table2array(data.Acceleration(:,2));
              accelerationZ = table2array(data.Acceleration(:,3));

            x=  plot(app.UIAccelerationGraph, accelerationX, 'r');
            y=  plot(app.UIAccelerationGraph, accelerationY);
                
            [X,Y]= meshgrid(x,y)
            
            %boxplot(app.UIAccelerationBoxPlotGraph,accelerationX) 
             % boxplot(app.UIAccelerationGraph,[accelerationX, accelerationY, accelerationZ], 'labels' , { 'X' , 'Y', 'Z' })
             figure 
             boxplot(table2array(data.Acceleration(:,1:2)));xlabel('X-Y')
            % boxplot (app.UIAccelerationGraph, accelerationX:accelerationZ, 'r');
              
             
          end   
          
          if isfield(data, 'Position')
              app.UICompassTable.Data = timetable2table(data.Position);
              positionX = table2array(data.Position(:,1));
              plot(app.UICompassGraph, positionX, 'r');
              
              figure
              v = data.Position(:,2) % represents longitude
              u = data.Position(:,1)
              th = linspace(pi/4,2*pi,10);
              r =linspace(min(table2array(u)),max(table2array(u)),10)
              [u,v] = pol2cart(th,r);
              hold on 
              c = compass(u,v)
              c1 = c(end); % the last data point collected 
              c1.LineWidth = 2;  
              c1.Color = 'r'; 
%           else
%               errordig('Please choose a file containing Acceleration', 'Error!');
          end
          
          if isfield(data, 'MagneticField')
              app.UICompassTable.Data = timetable2table(data.MagneticField);
              magneticFieldX = table2array(data.MagneticField(:,1));
              magneticFieldY = table2array(data.MagneticField(:,2));
              plot(app.UIMagneticFieldGraph, magneticFieldX, magneticFieldY, 'r');
          end
        end

        % Cell edit callback: UIAccelerationTable
        function UIAccelerationTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: ClearAccelerationDataButton
        function ClearAccelerationDataButtonPushed(app, event)
            if isempty(app.UIAccelerationTable.Data)
                return
            else
                app.UIAccelerationTable.Data(:,:)= [] ;
                cla(app.UIAccelerationGraph)
                cla(app.UIAccelerationBoxPlotGraph)
                
            end
        end

        % Button pushed function: ClearMagneticFieldDataButton
        function ClearMagneticFieldDataButtonPushed(app, event)
            if isempty(app.UIMagneticFieldGraph)
                return
            else
                cla(app.UIMagneticFieldGraph)
                
            end
        end

        % Button pushed function: ClearCompassDataButton
        function ClearCompassDataButtonPushed(app, event)
            if isempty(app.UICompassTable.Data)
                return
            else
                app.UICompassTable.Data(:,:)= [] ;
                cla(app.UICompassGraph)
                
            end        
        end

        % Button pushed function: DisplayMeanXYButton
        function DisplayMeanXYButtonPushed2(app, event)
           if isempty(app.UIAccelerationTable.Data.X)
               errordlg('Load Acceleration data first.', 'Error!')
           else 
               app.EditField.Value = mean(app.UIAccelerationTable.Data.X)
           end
        end

        % Button pushed function: DisplayStdXYButton
        function DisplayStdXYButtonPushed(app, event)
            if isempty(app.UIAccelerationTable.Data)
                errordlg('Load Acceleration data first.', 'Error!')
            else 
                app.DisplayStdEditField.Value = std(app.UIAccelerationTable.Data.X)
            end
        end

        % Button pushed function: CreateAccelerationCSVButton
        function CreateAccelerationCSVButtonPushed(app, event)
            
            accelerationTable = app.UIAccelerationTable.Data;                        
            [filename, ~]=uiputfile('*.csv', 'Save');
            if filename == 0
                return;
            end
            
            writetable(accelerationTable,filename)
        end

        % Button pushed function: CreateCompassCSVButton
        function CreateCompassCSVButtonPushed(app, event)
            
            compassTable = app.UICompassTable.Data;                        
            [filename, ~]=uiputfile('*.csv', 'Save');
            if filename == 0
                return;
            end
            
            writetable(compassTable,filename)
        end

        % Button pushed function: UploadtoThingSpeakButton
        function UploadtoThingSpeakButtonPushed(app, event)
            writeApiKey= '9KZ747IBQXUFPUHM';
            channelID= 1249697;
             
             Test= app.UIAccelerationTable.Data
             thingSpeakWrite(channelID,'Fields', [1,2,3], 'Values', Test(1:100:end, 2:end), 'WriteKey', writeApiKey, 'Timestamp', Test.Timestamp(1:100:end))
             
             Test2= app.UICompassTable.Data
             thingSpeakWrite(channelID,'Fields', [4,5,6], 'Values', Test2(1:100:end, 2:end), 'WriteKey', writeApiKey, 'Timestamp', Test2.Timestamp(1:100:end))
             
             msgbox('Data sent to thingspeak')
%             
% thingSpeakWrite(channelID, 'Field',3, Posit(:,2), 'WriteKey', writeApiKey, 'Longitude' Posit.Longitude(:));
%             
%             n= table2struct(app.UICompassTable);
              %isfield(n,'Longitude')
%                    Lat= app.UICompassTable.Data.latitude;
%                    Long= app.UICompassTable.Data.longitude;
%                    thingSpeakWrite(channelID, 'Field', 3, 'Values', Lat, 'WriteKey', writeApiKey, 'Longitude', Long)


%             if isempty(app.UIAccelerationTable.Data) && isempty(app.UICompassTable.Data)
%               eisempty(app.UIAccelerationTable.Data)== 0 && isempty(app.UICompassTable.Data) 
%                m= table2struct(app.UIAccelerrordlog('Please load Position and/or Acceleration sensor data first.', 'Error!'); % Error message if sensor data not uploaded
%             elseif rationTable);
%                isfield(m,'Timestamp')
%                    AccelerationX= app.UIAccelerationTable.Data.accelerationX;
%                    AccelerationY= app.UIAccelerationTable.Data.accelerationY;
%                    tStamp= app.UIAccelerationTable.Data.Timestamp;
%                    thingSpeakWrite(channelID, 'Fields', [1,2], 'Values', [AccelerationX,AccelerationY], 'WriteKey', writeApiKey, 'Timestamp', tStamp) % upload in fields 1-2
%                    
%                if isfield(m,'Timestamp')
%                    AccelerationX= app.UIAccelerationTable.Data.accelerationX;
%                    AccelerationY= app.UIAccelerationTable.Data.accelerationY;
%                    tStamp= app.UIAccelerationTable.Data.Timestamp;
%                    thingSpeakWrite(channelID, 'Fields', [1,2], 'Values', [AccelerationX,AccelerationY], 'WriteKey', writeApiKey, 'Timestamp', tStamp) % upload in fields 1-2
%                else 
%                    AccelerationX= app.UIAccelerationTable.Data.accelerationX;
%                    AccelerationY= app.UIAccelerationTable.Data.accelerationY;
%                    tStamp= app.UIAccelerationTable.Data.Timestamp;
%                    thingSpeakWrite(channelID, 'Fields', [1,2], 'Values', [AccelerationX,AccelerationY], 'WriteKey', writeApiKey, 'Timestamp', tStamp)
%                end
%            elseif isempty(app.UIAccelerationTable.Data) && isempty(app.UICompassTable.Data)==0
%                n= table2struct(app.UICompassTable);
%                if isfield(n,'Longitude')
%                    Lat= app.UICompassTable.Data.latitude;
%                    Long= app.UICompassTable.Data.longitude;
%                    thingSpeakWrite(channelID, 'Field', 3, 'Values', Lat, 'WriteKey', writeApiKey, 'Longitude', Long)
%                else
%                    Lat= app.UICompassTable.Data.latitude;
%                    Long= app.UICompassTable.Data.longitude;
%                    thingSpeakWrite(channelID, 'Field', 3, 'Values', Lat, 'WriteKey', writeApiKey, 'Longitude', Long)
%                end
%            else
%                m= table2struct(app.UIAccelerationTable);
%                n= table2struct(app.UICompassTable);
%                if isfield(n,'Longitude') && isfield(m,'Timestamp') 
%                    Lat= app.UICompassTable.Data.latitude;
%                    AccelerationX= app.UIAccelerationTable.Data.accelerationX;
%                    AccelerationY= app.UIAccelerationTable.Data.accelerationY;
%                    Long= app.UICompassTable.Data.longitude;
%                    tStamp= app.UIAccelerationTable.Data.Timestamp;
%                    thingSpeakWrite(channelID, 'Fields', [1,2,3], 'Values', [AccelerationX,AccelerationY,Lat], 'WriteKey', writeApiKey, 'Timestamp', [tStamp,Longitude])
%                else
%                    Lat= app.UICompassTable.Data.latitude;
%                    AccelerationX= app.UIAccelerationTable.Data.accelerationX;
%                    AccelerationY= app.UIAccelerationTable.Data.accelerationY;
%                    Long= app.UICompassTable.Data.longitude;
%                    tStamp= app.UIAccelerationTable.Data.Timestamp;
%                    thingSpeakWrite(channelID, 'Fields', [1,2,3], 'Values', [AccelerationX,AccelerationY,Lat], 'WriteKey', writeApiKey, 'Timestamp', [tStamp,Longitude])
%                end
%                end
        end

        % Button pushed function: ReadThingSpeakButton
        function ReadThingSpeakButtonPushed(app, event)
            %readApiKey= 'XDH61PY6GS95G9DH';
            channelID= 1249697;
            data= thingSpeakRead(channelID,'Fields', [1,2,3])
            data2= thingSpeakRead(channelID,'Fields', [4,5,6])
            
%             data= thingSpeakRead(channelID,'Fields', 1, 'NumPoints',30,'OutputFormat', 'TimeTable')
            %app.UIAccelerationTable=timetable2table(data)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Compass Image.jpg';
            app.ClearAccelerationDataButton = uibutton(app.UIFigure, 'push');
            app.ClearAccelerationDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAccelerationDataButtonPushed, true);
            app.ClearAccelerationDataButton.Text = 'Clear Data';
            app.ClearCompassDataButton = uibutton(app.UIFigure, 'push');
            app.ClearCompassDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearCompassDataButtonPushed, true);
            app.ClearCompassDataButton.Text = 'Clear Data';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data Button';
            app.ClearMagneticFieldDataButton = uibutton(app.UIFigure, 'push');
            app.ClearMagneticFieldDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMagneticFieldDataButtonPushed, true);
            app.ClearMagneticFieldDataButton.Text = 'Clear Data';
            app.SensorGroupOneCompassLabel = uilabel(app.UIFigure);
            app.SensorGroupOneCompassLabel.Text = 'Sensor Group One - Compass';
            app.UIAccelerationTable = uitable(app.UIFigure);
            app.UIAccelerationTable.ColumnName = {'Timestamp'; 'AccelerationX'; 'AccelerationY'; 'AccelerationZ'};
            app.UIAccelerationTable.RowName = {};
            app.UIAccelerationTable.CellEditCallback = createCallbackFcn(app, @UIAccelerationTableCellEdit, true);
            app.CreateAccelerationCSVButton = uibutton(app.UIFigure, 'push');
            app.CreateAccelerationCSVButton.ButtonPushedFcn = createCallbackFcn(app, @CreateAccelerationCSVButtonPushed, true);
            app.CreateAccelerationCSVButton.Text = 'Create Acceleration CSV';
            app.DisplayStdEditField = uieditfield(app.UIFigure, 'numeric');
            app.UICompassTable = uitable(app.UIFigure);
            app.UICompassTable.ColumnName = {'Timestamp'; 'Latitude'; 'Longitude'};
            app.UICompassTable.RowName = {};
            app.DisplayMeanXYButton = uibutton(app.UIFigure, 'push');
            app.DisplayMeanXYButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayMeanXYButtonPushed2, true);
            app.DisplayMeanXYButton.Text = 'Display Mean X & Y';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.DisplayStdXYButton = uibutton(app.UIFigure, 'push');
            app.DisplayStdXYButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayStdXYButtonPushed, true);
            app.DisplayStdXYButton.Text = 'Display Std X & Y';
            app.CreateCompassCSVButton = uibutton(app.UIFigure, 'push');
            app.CreateCompassCSVButton.ButtonPushedFcn = createCallbackFcn(app, @CreateCompassCSVButtonPushed, true);
            app.CreateCompassCSVButton.Text = 'Create Compass CSV';
            app.ReadThingSpeakButton = uibutton(app.UIFigure, 'push');
            app.ReadThingSpeakButton.ButtonPushedFcn = createCallbackFcn(app, @ReadThingSpeakButtonPushed, true);
            app.ReadThingSpeakButton.Text = 'Read ThingSpeak';
            app.UploadtoThingSpeakButton = uibutton(app.UIFigure, 'push');
            app.UploadtoThingSpeakButton.ButtonPushedFcn = createCallbackFcn(app, @UploadtoThingSpeakButtonPushed, true);
            app.UploadtoThingSpeakButton.Text = 'Upload to ThingSpeak';
            app.UIAccelerationGraph = uiaxes(app.UIFigure);
            title(app.UIAccelerationGraph, 'Acceleration vs. Time')
            xlabel(app.UIAccelerationGraph, 'Time (s)')
            ylabel(app.UIAccelerationGraph, 'Acceleration (m/s^2)')
            zlabel(app.UIAccelerationGraph, 'Z')
            app.UICompassGraph = uiaxes(app.UIFigure);
            title(app.UICompassGraph, 'Compass')
            xlabel(app.UICompassGraph, 'Longitude')
            ylabel(app.UICompassGraph, 'Latitude ')
            app.UIMagneticFieldGraph = uiaxes(app.UIFigure);
            title(app.UIMagneticFieldGraph, 'Magnetic Field ')
            xlabel(app.UIMagneticFieldGraph, 'x')
            ylabel(app.UIMagneticFieldGraph, 'y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FINALCompass_last

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end